classdef GenGui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestGen                   matlab.ui.Figure
        uipanel1                  matlab.ui.container.Panel
        uipanel8                  matlab.ui.container.Panel
        generateTest              matlab.ui.control.Button
        shortFeedback             matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        OutputTab                 matlab.ui.container.Tab
        OutputListBox             matlab.ui.control.ListBox
        ExcludeTab                matlab.ui.container.Tab
        ExcludeSolutionButton     matlab.ui.control.Button
        ExcludeVariablesLabel     matlab.ui.control.Label
        ExcludeVariablesSolution  matlab.ui.control.TextArea
        CustomPrintTab            matlab.ui.container.Tab
        EditField                 matlab.ui.control.EditField
        EditField_2               matlab.ui.control.EditField
        EditField_3               matlab.ui.control.EditField
        CustomString1Label        matlab.ui.control.Label
        CustomString2Label        matlab.ui.control.Label
        CustomString3Label        matlab.ui.control.Label
        uipanel7                  matlab.ui.container.Panel
        Test_browser              matlab.ui.control.Button
        testPath                  matlab.ui.control.TextArea
        uipanel5                  matlab.ui.container.Panel
        Learner_browser           matlab.ui.control.Button
        learnerPath               matlab.ui.control.TextArea
        uipanel6                  matlab.ui.container.Panel
        solutionPath              matlab.ui.control.TextArea
        Solution_browser          matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
    end


    properties (Access = public)
        %Initialize variables
        testFileName = []; 
        testFilePath = [];  
        solutionName = [];
        solutionFilePath = [];
        templateName = [];
        templateFilePath = [];  
        exSolVars = {};
        exTempVars = {};
        cstring1 = [];
        cstring2 = [];
        cstring3 = [];
    end


    methods (Access = private)

        % Code that executes after component creation
        function GenGui_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to GenGui (see VARARGIN)
%             
%             % Choose default command line output for GenGui
%             app.output = hObject;
             
            % Adds the functions subdirectory to matlab path on startup
            addpath('./Functions')
            
            %Centering GUI 
            movegui(app.TestGen, 'center');      
  
        end

        % Button pushed function: Learner_browser
        function Learner_browser_Callback(app, event)
            % hObject    handle to Learner_browser (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [FileName,FilePath] = uigetfile();
            
            % Fix to a problem where the UI window will get displaced and hidden 
            % behind other windows when using uigetfile(); method
            figure(app.TestGen);
            
            
            % Loads file if user selects it, clears file if user press cancel
            if(strcmp(class(FileName),'char'))
                app.learnerPath.Value= strcat(FilePath,FileName, ' loaded!');
                app.templateName = FileName;
                app.templateFilePath = FilePath;
                addpath(genpath(FilePath)); % Adds selected filepath to matlab directonary in order to run .m/.mat files.
                app.shortFeedback.Text = ('File Loaded');
            else       
                app.shortFeedback.Text = ('Selection aborted');
                app.learnerPath.Value = ('Select a Learner Template');
            end
            
        end

        % Button pushed function: Solution_browser
        function Solution_browser_Callback(app, event)
            % hObject    handle to Solution_browser (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,FilePath ]= uigetfile();
            
            % Fix to a problem where the UI window will get displaced and hidden 
            % behind other windows when using uigetfile(); method
            figure(app.TestGen);
            
            % Loads file if user selects it, clears file if user press cancel
            if(strcmp(class(FileName),'char'))
                app.solutionPath.Value = strcat(FilePath,FileName, 'loaded!');
                app.solutionName = FileName;
                app.solutionFilePath = FilePath;
                addpath(genpath(FilePath)); % Adds selected filepath to matlab directonary in order to run .m/.mat files.
                app.shortFeedback.Text = ('File Loaded');
            else       
                app.shortFeedback.Text = ('Selection aborted');
                app.solutionPath.Value = ('Select a Solution Template');
            end
         
        end

        % Button pushed function: Test_browser
        function Test_browser_Callback(app, event)
            % hObject    handle to Test_browser (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,FilePath]= uiputfile('Tests.txt');
            
            % Temp fix to a problem where the UI window will get displaced and hidden 
            % behind other windows when using uigetfile(); method
            figure(app.TestGen);
            
            
            if(strcmp(class(FileName),'char'))
                app.testPath.Value= strcat(FilePath,FileName);
                app.testFileName = FileName;
                app.testFilePath = FilePath;
                addpath(genpath(FilePath)); % Adds selected filepath to matlab directonary in order to run .m/.mat files.
                app.shortFeedback.Text = ('Location Selected');
            else       
                app.shortFeedback.Text = ('Selection aborted');
                app.testPath.Value = ('Select Location to save Text File');
            end    
        
        end

        % Button pushed function: generateTest
        function generateTest_Callback(app, event)
            % hObject    handle to generateTest (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
    
             id = [];
            
             % Retrieves path and name of test text file
             testName = app.testFileName;
             testPath = app.testFilePath;

         
             excludeSol = app.exSolVars;
             
             % Uncomment to Export to workspace for testing
             % assignin('base', 'excludeSol', excludeSol);
             
             cstring1 = app.cstring1;
             cstring2 = app.cstring2;
             cstring3 = app.cstring3;
            
            try
                % Check if all the paths and fields are selected    
                if~(isempty(app.testFilePath) || isempty(app.solutionFilePath) || isempty(app.templateFilePath)) 
                   
                    Solutions = app.solutionName;    
                    Student   = app.templateName;
                        
                    % Imports variables from both the student and solution template.
                    [Solutions, Student] = importVariables(Solutions, Student, excludeSol);
                    
                    
                    % Compares the structures and returns a new structure
                    % with the elements uncommon the two input structures.
                    [uncommonElements] = compareStructures(Solutions, Student);
                    
                    % Uncomment to Export to workspace for testing
                    % assignin('base', 'inSolnotStud', inSolnotStud);
                    % assignin('base', 'inStudnotSol', inStudnotSol);
                    
                    % Exports variables to Matlab Workspace for debugging purposes.
                    % assignin('base', 'SolutionsUnfiltered', Solutions)
                    % assignin('base', 'StudentUnfiltered', Student)
                    
                    % Trims the solution structure to remove the uncommon elements
                    % Will return an structure with the common elements 

                    [commonElements] = removeUncommon(Solutions, uncommonElements);

                    % Uncomment to Export to workspace for testing
                    % Exports variables to Matlab Workspace for debugging purposes.
                    % assignin('base', 'SolutionsFinal', Solutions)
                    % assignin('base', 'StudentFinal', Student)
                       
                    printTest(commonElements, testPath, testName, cstring1, cstring2, cstring3);
                    app.shortFeedback.Text = 'Tests successfully generated!';
                    Str1 = string(['Done! Please check:' newline testPath newline 'for text file']);
                    
                    Str2 = string(['Solution variables omitted: ' excludeSol']);
                       
                    scroll(app.OutputListBox, 'bottom');
                    app.OutputListBox.Items =  [app.OutputListBox.Items, Str1];
                   
                    
                    scroll(app.OutputListBox, 'bottom');
                    app.OutputListBox.Items =  [app.OutputListBox.Items, Str2];

                else
                    ErrorStr = (['Not all files have been selected']);
                    scroll(app.OutputListBox, 'bottom');
                    app.OutputListBox.Items =  [app.OutputListBox.Items, ErrorStr];  
                end
                
            catch ME % MException struct
                debug = getReport(ME)
                id = ME.identifier
                
                msgText = getReport(ME,'extended', 'hyperlinks', 'off');
                scroll(app.OutputListBox, 'bottom');
                app.OutputListBox.Items =  [app.OutputListBox.Items, msgText];
               
 
            % Case dependent on Exception identifier. 
            % Add exceptions here, with appropriate feedback.    
            switch ME.identifier
                case 'MATLAB:run:FileNotFound'
                    app.shortFeedback.Text = 'File(s) not found. Please select a valid matlab file.';  
            end 
               
   
            end
         
        end

        % Button pushed function: ExcludeSolutionButton
        function ExcludeSolutionButtonPushed(app, event)
            app.exSolVars = app.ExcludeVariablesSolution.Value;    
        end

        % Value changed function: ExcludeVariablesSolution
        function ExcludeVariablesSolutionValueChanged(app, event)
          %  value = app.ExcludeVariablesSolution.Value;   
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Resets application values to default 
            app.OutputListBox.Items = {'OUTPUT:'};
            app.testPath.Value = ('Select Location to save Text File');
            app.testFileName = ('');
            app.testFilePath = ('');
            app.solutionPath.Value = ('Select a Solution Template');
            app.solutionName = ('');
            app.solutionFilePath = ('');
            app.learnerPath.Value = ('Select a Learner Template');
            app.templateFilePath = ('');
            app.templateName = ('');
            app.shortFeedback.Text = ('');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
             app.cstring1 = app.EditField.Value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
             app.cstring2 =app.EditField_2.Value;    
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.cstring3 =  app.EditField_3.Value;  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestGen = uifigure;
            app.TestGen.Name = 'TestGenerator';
            app.uipanel1 = uipanel(app.TestGen);
            app.uipanel8 = uipanel(app.uipanel1);
            app.generateTest = uibutton(app.uipanel8, 'push');
            app.generateTest.ButtonPushedFcn = createCallbackFcn(app, @generateTest_Callback, true);
            app.generateTest.Text = 'Generate Tests';
            app.shortFeedback = uilabel(app.uipanel8);
            app.shortFeedback.Text = '';
            app.TabGroup = uitabgroup(app.uipanel8);
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.OutputListBox = uilistbox(app.OutputTab);
            app.OutputListBox.Items = {'OUTPUT:', ''};
            app.OutputListBox.Value = {};
            app.ExcludeTab = uitab(app.TabGroup);
            app.ExcludeTab.Title = 'Exclude';
            app.ExcludeSolutionButton = uibutton(app.ExcludeTab, 'push');
            app.ExcludeSolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeSolutionButtonPushed, true);
            app.ExcludeSolutionButton.Text = 'Submit';
            app.ExcludeVariablesLabel = uilabel(app.ExcludeTab);
            app.ExcludeVariablesLabel.Text = 'Exclude Variables';
            app.ExcludeVariablesSolution = uitextarea(app.ExcludeTab);
            app.ExcludeVariablesSolution.ValueChangedFcn = createCallbackFcn(app, @ExcludeVariablesSolutionValueChanged, true);
            app.ExcludeVariablesSolution.Value = {''; ''};
            app.CustomPrintTab = uitab(app.TabGroup);
            app.CustomPrintTab.Title = 'Custom Print';
            app.EditField = uieditfield(app.CustomPrintTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.CustomPrintTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.CustomPrintTab, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.CustomString1Label = uilabel(app.CustomPrintTab);
            app.CustomString1Label.Text = 'Custom String 1';
            app.CustomString2Label = uilabel(app.CustomPrintTab);
            app.CustomString2Label.Text = 'Custom String 2';
            app.CustomString3Label = uilabel(app.CustomPrintTab);
            app.CustomString3Label.Text = 'Custom String 3';
            app.uipanel7 = uipanel(app.uipanel1);
            app.uipanel7.Title = 'Test';
            app.Test_browser = uibutton(app.uipanel7, 'push');
            app.Test_browser.ButtonPushedFcn = createCallbackFcn(app, @Test_browser_Callback, true);
            app.Test_browser.Text = 'Browse';
            app.testPath = uitextarea(app.uipanel7);
            app.testPath.Editable = 'off';
            app.testPath.Value = {'Select Location to save Text File'; ''};
            app.uipanel5 = uipanel(app.uipanel1);
            app.uipanel5.Title = 'Learner Template';
            app.Learner_browser = uibutton(app.uipanel5, 'push');
            app.Learner_browser.ButtonPushedFcn = createCallbackFcn(app, @Learner_browser_Callback, true);
            app.Learner_browser.Text = 'Browse';
            app.learnerPath = uitextarea(app.uipanel5);
            app.learnerPath.Editable = 'off';
            app.learnerPath.Value = {'Select a Learner Template'};
            app.uipanel6 = uipanel(app.uipanel1);
            app.uipanel6.Title = 'Solution Template';
            app.solutionPath = uitextarea(app.uipanel6);
            app.solutionPath.Editable = 'off';
            app.solutionPath.Value = {'Select a Solution Template'; ''};
            app.Solution_browser = uibutton(app.uipanel6, 'push');
            app.Solution_browser.ButtonPushedFcn = createCallbackFcn(app, @Solution_browser_Callback, true);
            app.Solution_browser.Text = 'Browse';
            app.ResetButton = uibutton(app.uipanel1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = GenGui_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestGen)

            % Execute the startup function
            runStartupFcn(app, @GenGui_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestGen)
        end
    end
end